classdef EM_121EE0346 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditField_3           matlab.ui.control.EditField
        EditField2_8          matlab.ui.control.EditField
        EditField2_7          matlab.ui.control.EditField
        EditField2_6          matlab.ui.control.EditField
        EditField2_5          matlab.ui.control.EditField
        EditField2_4          matlab.ui.control.EditField
        EditField2_3          matlab.ui.control.EditField
        EditField2_2          matlab.ui.control.EditField
        EditField2            matlab.ui.control.EditField
        EditField_2           matlab.ui.control.EditField
        VREditField           matlab.ui.control.NumericEditField
        VRLabel               matlab.ui.control.Label
        calculateButton_4     matlab.ui.control.StateButton
        E2EditField           matlab.ui.control.NumericEditField
        E2EditFieldLabel      matlab.ui.control.Label
        calculateButton_3     matlab.ui.control.StateButton
        SINPHYEditField       matlab.ui.control.NumericEditField
        SINPHYEditFieldLabel  matlab.ui.control.Label
        V2EditField           matlab.ui.control.NumericEditField
        V2EditFieldLabel      matlab.ui.control.Label
        COSPHYEditField       matlab.ui.control.NumericEditField
        COSPHYEditFieldLabel  matlab.ui.control.Label
        I2EditField           matlab.ui.control.NumericEditField
        I2EditFieldLabel      matlab.ui.control.Label
        X02EditField          matlab.ui.control.NumericEditField
        X02EditFieldLabel     matlab.ui.control.Label
        calculateButton_2     matlab.ui.control.StateButton
        X2EditField           matlab.ui.control.NumericEditField
        X2EditFieldLabel      matlab.ui.control.Label
        X1EditField           matlab.ui.control.NumericEditField
        X1EditFieldLabel      matlab.ui.control.Label
        R02EditField          matlab.ui.control.NumericEditField
        R02EditFieldLabel     matlab.ui.control.Label
        calculateButton       matlab.ui.control.StateButton
        KEditField            matlab.ui.control.NumericEditField
        KEditFieldLabel       matlab.ui.control.Label
        R2EditField           matlab.ui.control.NumericEditField
        R2EditFieldLabel      matlab.ui.control.Label
        R1EditField           matlab.ui.control.NumericEditField
        R1EditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: calculateButton
        function calculateButtonValueChanged(app, event)
          R1 = app.R1EditField.Value;
          R2 = app.R2EditField.Value;
          K = app.KEditField.Value;
          R02 = R2+R1*K*K;
          app.R02EditField.Value = R02;
            
        end

        % Value changed function: calculateButton_2
        function calculateButton_2ValueChanged(app, event)
            value = app.calculateButton_2.Value;
            X1 = app.X1EditField.Value;
            X2 = app.X2EditField.Value;
            K = app.KEditField.Value;
            X02 = X2+X1*K*K;
            app.X02EditField.Value = X02;
        end

        % Value changed function: calculateButton_3
        function calculateButton_3ValueChanged(app, event)
            value = app.calculateButton_3.Value;
            I2 = app.I2EditField.Value;
            COSPHY = app.COSPHYEditField.Value;
            V2 = app.V2EditField.Value;
            SINPHY = app.SINPHYEditField.Value;
            R02 = app.R02EditField.Value;
            X02 = app.X02EditField.Value;
            E2 = (I2*R02*COSPHY)+(I2*X02*SINPHY)+V2;
            app.E2EditField.Value = E2;
        end

        % Callback function: not associated with a component
        function calculateButton_4Pushed(app, event)
          
        end

        % Value changed function: calculateButton_4
        function calculateButton_4ValueChanged(app, event)
            value = app.calculateButton_4.Value;
           E2 = app.E2EditField.Value;
           V2 = app.V2EditField.Value;
           VR = 100*(E2-V2)*1/E2;
           app.VREditField.Value = VR;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.calculateButton = uibutton(app.UIFigure, 'state');
            app.calculateButton.ValueChangedFcn = createCallbackFcn(app, @calculateButtonValueChanged, true);
            app.calculateButton.Text = 'calculate';
            app.R02EditFieldLabel = uilabel(app.UIFigure);
            app.R02EditFieldLabel.Text = 'R02';
            app.R02EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.calculateButton_2 = uibutton(app.UIFigure, 'state');
            app.calculateButton_2.ValueChangedFcn = createCallbackFcn(app, @calculateButton_2ValueChanged, true);
            app.calculateButton_2.Text = 'calculate';
            app.X02EditFieldLabel = uilabel(app.UIFigure);
            app.X02EditFieldLabel.Text = 'X02';
            app.X02EditField = uieditfield(app.UIFigure, 'numeric');
            app.I2EditFieldLabel = uilabel(app.UIFigure);
            app.I2EditFieldLabel.Text = 'I2';
            app.I2EditField = uieditfield(app.UIFigure, 'numeric');
            app.COSPHYEditFieldLabel = uilabel(app.UIFigure);
            app.COSPHYEditFieldLabel.Text = 'COSPHY';
            app.COSPHYEditField = uieditfield(app.UIFigure, 'numeric');
            app.V2EditFieldLabel = uilabel(app.UIFigure);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SINPHYEditFieldLabel = uilabel(app.UIFigure);
            app.SINPHYEditFieldLabel.Text = 'SINPHY';
            app.SINPHYEditField = uieditfield(app.UIFigure, 'numeric');
            app.calculateButton_3 = uibutton(app.UIFigure, 'state');
            app.calculateButton_3.ValueChangedFcn = createCallbackFcn(app, @calculateButton_3ValueChanged, true);
            app.calculateButton_3.Text = 'calculate';
            app.E2EditFieldLabel = uilabel(app.UIFigure);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.UIFigure, 'numeric');
            app.calculateButton_4 = uibutton(app.UIFigure, 'state');
            app.calculateButton_4.ValueChangedFcn = createCallbackFcn(app, @calculateButton_4ValueChanged, true);
            app.calculateButton_4.Text = 'calculate';
            app.VRLabel = uilabel(app.UIFigure);
            app.VRLabel.Text = 'VR';
            app.VREditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'VOLTAGE REGULATION OF A TRANSFORMER';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Value = '%VOLTAGE REGULATION';
            app.EditField2_2 = uieditfield(app.UIFigure, 'text');
            app.EditField2_2.Value = 'TERMINAL VOLTAGE';
            app.EditField2_3 = uieditfield(app.UIFigure, 'text');
            app.EditField2_3.Value = 'COSPHY=POWERFACTOR';
            app.EditField2_4 = uieditfield(app.UIFigure, 'text');
            app.EditField2_4.Value = 'PRIMARY REACTANCE';
            app.EditField2_5 = uieditfield(app.UIFigure, 'text');
            app.EditField2_5.Value = 'SECONDARY REACTANCE';
            app.EditField2_6 = uieditfield(app.UIFigure, 'text');
            app.EditField2_6.Value = 'PRIMARY RESISTANCE';
            app.EditField2_7 = uieditfield(app.UIFigure, 'text');
            app.EditField2_7.Value = 'SECONDARY RESISTANCE';
            app.EditField2_8 = uieditfield(app.UIFigure, 'text');
            app.EditField2_8.Value = 'TRANSFORMATION RATIO';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '    FOR LAGGING POWERFACTOR PLEASE ENTER NEGATIVE OF SINPHY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EM_121EE0346

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end